classdef Dicom_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SaveasPDFButton      matlab.ui.control.Button
        DreptunghiButton     matlab.ui.control.Button
        DisplayIMG           matlab.ui.control.Button
        DesplayConturButton  matlab.ui.control.Button
        SRNEditField         matlab.ui.control.NumericEditField
        FileEditField        matlab.ui.control.EditField
        SRNLabel             matlab.ui.control.Label
        DSEditField          matlab.ui.control.NumericEditField
        AEditField_2Label    matlab.ui.control.Label
        AEditField           matlab.ui.control.NumericEditField
        AEditFieldLabel      matlab.ui.control.Label
        CalculareButton      matlab.ui.control.Button
        CercButton           matlab.ui.control.Button
        LoadFileButton       matlab.ui.control.Button
        FileEditFieldLabel   matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        info % Description
        image % Description
        image_loaded % Description
        imaginefinala
        contur = false
    end
    
    properties (Access = public)
        circle % Description
        dreptunghi % Description
        cerc_isactiv = false % Description
        dreptunghi_isactiv = false % Description
        img_isactive = false % Description
        ROI % Description
        A % Description
        DS % Description
        SNR % Description
    end
    
    methods (Access = private)
        
        function generate_ROI_cerc(app)
            app.ROI = [];
            x0 = app.circle.Center(1);
            y0 = app.circle.Center(2);
            r = app.circle.Radius; 
            
            Lx = app.info.Rows;
            Ly = app.info.Columns;

            for i = 1:Lx
                for j = 1:Ly
                    if (i-x0)^2 + (j-y0)^2 < r
                        app.ROI = [app.ROI; i, j, app.image(i,j)];
                    end
                end
            end
            app.A = median(app.ROI(:,3));
            app.DS = std(double(app.ROI(:,3)));
            app.SNR = double(app.A)/double(app.DS);
        end
        
        function generate_ROI_dreptunghi(app)
            app.ROI = [];
            x = app.dreptunghi.Position(1);
            y = app.dreptunghi.Position(2);
            w = app.dreptunghi.Position(3);
            h = app.dreptunghi.Position(4);
            
            Lx = app.info.Rows;
            Ly = app.info.Columns;
            x = max(1, min(x, Lx));
            y = max(1, min(y, Ly));
            w = max(1, min(w, Lx));
            h = max(1, min(h, Ly));
            hold(app.UIAxes, 'on');
            for i = x:x+w
                for j = y:y+h
                    app.ROI = [app.ROI; i, j, app.image(i,j)];
                end 
            end
            app.A = median(app.ROI(:, 3));
            app.DS = std(double(app.ROI(:, 3)));
            app.SNR = double(app.A) / double(app.DS);
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible="off";
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile('*.dcm');   
            if ~isequal(file,0)
                filename = fullfile(path,file);
                app.info = dicominfo(filename);
                app.image = dicomread(app.info);
                app.image_loaded = true;
                app.FileEditField.Value = filename;
            end

        end

        % Button pushed function: DisplayIMG
        function DisplayImageCheckBoxValueChanged(app, event)
            if app.image_loaded
                app.img_isactive = true;
                app.UIAxes.Visible="on";
                imshow(app.image, [], 'Parent', app.UIAxes);
                app.cerc_isactiv = false;
                app.dreptunghi_isactiv = false;
             end
        end

        % Button pushed function: CalculareButton
        function CalculareButtonPushed(app, event)
            if app.cerc_isactiv
                app.generate_ROI_cerc(); 
                app.AEditField.Value = double(app.A);
                app.DSEditField.Value = double(app.DS);
                app.SRNEditField.Value = double(app.SNR);
            elseif app.dreptunghi_isactiv
                app.generate_ROI_dreptunghi(); 
                app.AEditField.Value = double(app.A);
                app.DSEditField.Value = double(app.DS);
                app.SRNEditField.Value = double(app.SNR);
            else
                app.AEditField.Value = 0;
                app.DSEditField.Value = 0;
                app.SRNEditField.Value = 0;
            end
        end

        % Button pushed function: DesplayConturButton
        function DesplayConturButtonPushed(app, event)
            hold(app.UIAxes, 'on');
            contour(app.UIAxes, app.image, 1500:500:2500, 'r');
            contour(app.UIAxes, app.image, 500:10:600, 'g');
            contour(app.UIAxes, app.image, 500:50:700, 'b');
            contour(app.UIAxes, app.image, 200:50:700, 'b');
            contour(app.UIAxes, app.image, 500:10:600, 'b');
            hold(app.UIAxes, 'off');
            app.contur = true;
        end

        % Button pushed function: DreptunghiButton
        function DreptunghiButtonPushed(app, event)
             if app.img_isactive && app.cerc_isactiv == false && app.dreptunghi_isactiv == false
                app.dreptunghi_isactiv = true;
                app.UIFigure.HandleVisibility = 'callback';
                app.dreptunghi = drawrectangle(app.UIAxes);
                app.UIFigure.HandleVisibility = 'off';               
             end
        end

        % Button pushed function: CercButton
        function CercButtonPushed(app, event)
            if app.img_isactive && app.cerc_isactiv == false && app.dreptunghi_isactiv == false
                app.cerc_isactiv = true;
                app.UIFigure.HandleVisibility = 'callback';
                app.circle = drawcircle(app.UIAxes);
                app.UIFigure.HandleVisibility = 'off';
            end
        end

        % Button pushed function: SaveasPDFButton
        function SaveasPDFButtonPushed(app, event)
            if app.img_isactive
                imshow(app.image, []);
                if app.contur
                    hold on;
                    contour(app.image,1500:500:2500,'r');
                    contour(app.image,500:10:600,'g');
                    contour(app.image,500:50:700,'b');
                    contour(app.image,200:50:700,'b');
                    contour(app.image,500:10:600,'b');
                    hold off
                end
                if app.cerc_isactiv
                    hold on;
                    x = app.circle.Center(1);
                    y = app.circle.Center(2);
                    drawcircle(Radius=app.circle.Radius, Center=[x,y]);
                    hold off;
                end
                if app.dreptunghi_isactiv
                    hold on;
                    x = app.dreptunghi.Position(1);
                    y = app.dreptunghi.Position(2);
                    w = app.dreptunghi.Position(3);
                    h = app.dreptunghi.Position(4);
                    drawrectangle("Position",[x,y,w,h])
                    hold off;
                end
                [filename, filepath] = uiputfile('*.pdf');
                saveas(gcf, [filepath filename]);
                close all;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagine Dicom')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.CercButton = uibutton(app.UIFigure, 'push');
            app.CercButton.ButtonPushedFcn = createCallbackFcn(app, @CercButtonPushed, true);
            app.CercButton.Text = 'Cerc';
            app.CalculareButton = uibutton(app.UIFigure, 'push');
            app.CalculareButton.ButtonPushedFcn = createCallbackFcn(app, @CalculareButtonPushed, true);
            app.CalculareButton.Text = 'Calculare ';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = '<A>=';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.AllowEmpty = 'on';
            app.AEditField.Editable = 'off';
            app.AEditField_2Label = uilabel(app.UIFigure);
            app.AEditField_2Label.Text = 'σA=';
            app.DSEditField = uieditfield(app.UIFigure, 'numeric');
            app.DSEditField.AllowEmpty = 'on';
            app.DSEditField.Editable = 'off';
            app.SRNLabel = uilabel(app.UIFigure);
            app.SRNLabel.Text = 'SRN=';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.SRNEditField = uieditfield(app.UIFigure, 'numeric');
            app.SRNEditField.AllowEmpty = 'on';
            app.SRNEditField.Editable = 'off';
            app.DesplayConturButton = uibutton(app.UIFigure, 'push');
            app.DesplayConturButton.ButtonPushedFcn = createCallbackFcn(app, @DesplayConturButtonPushed, true);
            app.DesplayConturButton.Text = 'Desplay Contur';
            app.DisplayIMG = uibutton(app.UIFigure, 'push');
            app.DisplayIMG.ButtonPushedFcn = createCallbackFcn(app, @DisplayImageCheckBoxValueChanged, true);
            app.DisplayIMG.Text = 'Desplay Img';
            app.DreptunghiButton = uibutton(app.UIFigure, 'push');
            app.DreptunghiButton.ButtonPushedFcn = createCallbackFcn(app, @DreptunghiButtonPushed, true);
            app.DreptunghiButton.Text = 'Dreptunghi';
            app.SaveasPDFButton = uibutton(app.UIFigure, 'push');
            app.SaveasPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPDFButtonPushed, true);
            app.SaveasPDFButton.Text = 'Save as PDF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dicom_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end